classdef GUIlab4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        VerposicionactualButton  matlab.ui.control.Button
        verultimaposicionenviadalButton  matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        VictorDavilaManuelCarranzaLabel  matlab.ui.control.Label
        iralaposicinButton       matlab.ui.control.Button
        Posicionesposiblesq1q2q3q4ButtonGroup  matlab.ui.container.ButtonGroup
        Button_5                 matlab.ui.control.RadioButton
        Button_4                 matlab.ui.control.RadioButton
        Button_3                 matlab.ui.control.RadioButton
        Button_2                 matlab.ui.control.RadioButton
        Button                   matlab.ui.control.RadioButton
    end


    properties (Access = public)
        q1 % Description
        q2
        q3
        q4

        % Control table address
        ADDR_MX_TORQUE_ENABLE       = 24;           % Control table address is different in Dynamixel model
        ADDR_MX_GOAL_POSITION       = 30;
        ADDR_MX_PRESENT_POSITION    = 36;
        
        % Data Byte Length
        LEN_MX_GOAL_POSITION        = 2;
        LEN_MX_PRESENT_POSITION     = 2;
        
        % Protocol version
        PROTOCOL_VERSION            = 1.0;          % See which protocol version is used in the Dynamixel
        
        % Default setting
        DXL1_ID                     = 1;            % Dynamixel#1 ID: 1
        DXL2_ID                     = 2;            % Dynamixel#2 ID: 2
        DXL3_ID                     = 3;
        DXL4_ID                     = 4;
        BAUDRATE                    = 1000000;
        DEVICENAME                  = 'COM5';       % Check which port is being used on your controller
                                                    % ex) Windows: 'COM1'   Linux: '/dev/ttyUSB0' Mac: '/dev/tty.usbserial-*'
        
        TORQUE_ENABLE               = 1;            % Value for enabling the torque
        TORQUE_DISABLE              = 0;            % Value for disabling the torque
        DXL_MINIMUM_POSITION_VALUE  = 500;          % Dynamixel will rotate between this value
        DXL_MAXIMUM_POSITION_VALUE  = 550;         % and this value (note that the Dynamixel would not move when the position value is out of movable range. Check e-manual about the range of the Dynamixel you use.)
        DXL_MOVING_STATUS_THRESHOLD = 10;           % Dynamixel moving status threshold
        
        ESC_CHARACTER               = 'e';          % Key for escaping loop
        
        COMM_SUCCESS                = 0;            % Communication Success result value
        COMM_TX_FAIL                = -1001;        % Communication Tx Failed
        port_num ;
        group_num;
        dxl_error = 0;                              % Dynamixel error
        dxl1_present_position = 0;                  % Present position
        dxl2_present_position = 0;
        dxl3_present_position = 0;
        dxl4_present_position = 0;
        dxl_comm_result;
        dxl_addparam_result;
        dxl_goal_position;
        ROBOT
    end


    methods (Access = public)
        
        function bitsval = mapeo(app,ang)
            ang2=ang+180;
            bitsval=round(ang2*1024/360);
        end

        function angval = inversemap(app, bitsval)
            bits2=bitsval*360/1024;
            angval=bits2-180;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            lib_name='dxl_x64_c';
            if ~libisloaded(lib_name);
                [notfound, warnings] = loadlibrary('dxl_x64_c', 'dynamixel_sdk.h', 'addheader', 'port_handler.h', 'addheader', 'packet_handler.h', 'addheader', 'group_sync_write.h');
            end
            app.port_num = portHandler(app.DEVICENAME);
            app.q1 = 0;
            app.q2 = pi/2;
            app.q3 = 0;
            app.q4 = 0;
            
            
            app.UITable.Data=["q1",app.q1;"q2",app.q2-pi/2;"q3",app.q3;"q4",app.q4;];
            packetHandler();
            app.group_num = groupSyncWrite(app.port_num, app.PROTOCOL_VERSION, app.ADDR_MX_GOAL_POSITION, app.LEN_MX_GOAL_POSITION);
            app.dxl_comm_result = app.COMM_TX_FAIL;             % Communication result
            app.dxl_addparam_result = false;                % AddParam result
            app.dxl_goal_position = [app.DXL_MINIMUM_POSITION_VALUE app.DXL_MAXIMUM_POSITION_VALUE];         % Goal position
            if (openPort(app.port_num))
                fprintf('Succeeded to open the port!\n');
            else
                unloadlibrary(lib_name);
                fprintf('Failed to open the port!\n');
                input('Press any key to terminate...\n');
                return;
            end
            if (setBaudRate(app.port_num, app.BAUDRATE))
                fprintf('Succeeded to change the baudrate!\n');
            else
                unloadlibrary(lib_name);
                fprintf('Failed to change the baudrate!\n');
                input('Press any key to terminate...\n');
                return;
            end


            write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL1_ID, app.ADDR_MX_TORQUE_ENABLE, app.TORQUE_ENABLE);
            app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            app.dxl_error = getLastRxPacketError(app.port_num, app.PROTOCOL_VERSION);
            if app.dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
            elseif app.dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, app.dxl_error));
            else
                fprintf('Dynamixel has been successfully connected \n');
            end
            
            write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL2_ID, app.ADDR_MX_TORQUE_ENABLE, app.TORQUE_ENABLE);
            app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            app.dxl_error = getLastRxPacketError(app.port_num, app.PROTOCOL_VERSION);
            if app.dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
            elseif app.dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, app.dxl_error));
            else
                fprintf('Dynamixel has been successfully connected \n');
            end

            write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL3_ID, app.ADDR_MX_TORQUE_ENABLE, app.TORQUE_ENABLE);
            app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            app.dxl_error = getLastRxPacketError(app.port_num, app.PROTOCOL_VERSION);
            if app.dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
            elseif app.dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, app.dxl_error));
            else
                fprintf('Dynamixel has been successfully connected \n');
            end

            write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL4_ID, app.ADDR_MX_TORQUE_ENABLE, app.TORQUE_ENABLE);
            app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            app.dxl_error = getLastRxPacketError(app.port_num, app.PROTOCOL_VERSION);
            if app.dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
            elseif app.dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, app.dxl_error));
            else
                fprintf('Dynamixel has been successfully connected \n');
            end
            
            % Establecer Límites de Visualización
           

        end

        % Button down function: Posicionesposiblesq1q2q3q4ButtonGroup
        function Posicionesposiblesq1q2q3q4ButtonGroupButtonDown(app, event)
            
        end

        % Button pushed function: iralaposicinButton
        function iralaposicinButtonPushed(app, event)
            if app.Button.Value==1
                app.q1 = 0;
                app.q2 = pi/2;
                app.q3 = 0;
                app.q4 = 0;
                app.UITable.Data=["q1",app.q1*180/pi;"q2",app.q2*180/pi-90;"q3",app.q3*180/pi;"q4",app.q4*180/pi;];
                q1=mapeo(app,app.q1*180/pi);
                q2=mapeo(app,(app.q2-pi/2)*180/pi);
                q3=mapeo(app,app.q3*180/pi);
                q4=mapeo(app,app.q4*180/pi);
                
                %mandar al robot a la posicion
                
                app.dxl_addparam_result = groupSyncWriteAddParam(app.group_num, app.DXL1_ID, q1, app.LEN_MX_GOAL_POSITION);
                if app.dxl_addparam_result ~= true
                    fprintf('[ID:%03d] groupSyncWrite addparam failed', app.DXL1_ID);
                    return;
                end
                app.dxl_addparam_result = groupSyncWriteAddParam(app.group_num, app.DXL2_ID, q2, app.LEN_MX_GOAL_POSITION);
                if app.dxl_addparam_result ~= true
                    fprintf('[ID:%03d] groupSyncWrite addparam failed', app.DXL2_ID);
                    return;
                end

                app.dxl_addparam_result = groupSyncWriteAddParam(app.group_num, app.DXL3_ID, q3, app.LEN_MX_GOAL_POSITION);
                if app.dxl_addparam_result ~= true
                    fprintf('[ID:%03d] groupSyncWrite addparam failed', app.DXL3_ID);
                    return;
                end

                app.dxl_addparam_result = groupSyncWriteAddParam(app.group_num, app.DXL4_ID, q4, app.LEN_MX_GOAL_POSITION);
                if app.dxl_addparam_result ~= true
                    fprintf('[ID:%03d] groupSyncWrite addparam failed', app.DXL4_ID);
                    return;
                end

                groupSyncWriteTxPacket(app.group_num);
                app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
                if app.dxl_comm_result ~= app.COMM_SUCCESS
                    fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
                end
                groupSyncWriteClearParam(app.group_num);

                disp("1");
            elseif app.Button_2.Value==1
                app.q1 = 25*pi/180;
                app.q2 = pi/2+25*pi/180;
                app.q3 = 20*pi/180;
                app.q4 = -20*pi/180;
                app.UITable.Data=["q1",app.q1*180/pi;"q2",app.q2*180/pi-90;"q3",app.q3*180/pi;"q4",app.q4*180/pi;];
                q1=mapeo(app,app.q1*180/pi);
                q2=mapeo(app,(app.q2-pi/2)*180/pi);
                q3=mapeo(app,app.q3*180/pi);
                q4=mapeo(app,app.q4*180/pi);
                
                %mandar al robot a la posicion
                
                app.dxl_addparam_result = groupSyncWriteAddParam(app.group_num, app.DXL1_ID, q1, app.LEN_MX_GOAL_POSITION);
                if app.dxl_addparam_result ~= true
                    fprintf('[ID:%03d] groupSyncWrite addparam failed', app.DXL1_ID);
                    return;
                end
                app.dxl_addparam_result = groupSyncWriteAddParam(app.group_num, app.DXL2_ID, q2, app.LEN_MX_GOAL_POSITION);
                if app.dxl_addparam_result ~= true
                    fprintf('[ID:%03d] groupSyncWrite addparam failed', app.DXL2_ID);
                    return;
                end

                app.dxl_addparam_result = groupSyncWriteAddParam(app.group_num, app.DXL3_ID, q3, app.LEN_MX_GOAL_POSITION);
                if app.dxl_addparam_result ~= true
                    fprintf('[ID:%03d] groupSyncWrite addparam failed', app.DXL3_ID);
                    return;
                end

                app.dxl_addparam_result = groupSyncWriteAddParam(app.group_num, app.DXL4_ID, q4, app.LEN_MX_GOAL_POSITION);
                if app.dxl_addparam_result ~= true
                    fprintf('[ID:%03d] groupSyncWrite addparam failed', app.DXL4_ID);
                    return;
                end

                groupSyncWriteTxPacket(app.group_num);
                app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
                if app.dxl_comm_result ~= app.COMM_SUCCESS
                    fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
                end
                groupSyncWriteClearParam(app.group_num);

                disp("2");
            elseif app.Button_3.Value==1
                app.q1 = -35*pi/180;
                app.q2 = pi/2+35*pi/180;
                app.q3 = -30*pi/180;
                app.q4 = 30*pi/180;
                app.UITable.Data=["q1",app.q1*180/pi;"q2",app.q2*180/pi-90;"q3",app.q3*180/pi;"q4",app.q4*180/pi;];
                q1=mapeo(app,app.q1*180/pi);
                q2=mapeo(app,(app.q2-pi/2)*180/pi);
                q3=mapeo(app,app.q3*180/pi);
                q4=mapeo(app,app.q4*180/pi);
                
                %mandar al robot a la posicion
                
                app.dxl_addparam_result = groupSyncWriteAddParam(app.group_num, app.DXL1_ID, q1, app.LEN_MX_GOAL_POSITION);
                if app.dxl_addparam_result ~= true
                    fprintf('[ID:%03d] groupSyncWrite addparam failed', app.DXL1_ID);
                    return;
                end
                app.dxl_addparam_result = groupSyncWriteAddParam(app.group_num, app.DXL2_ID, q2, app.LEN_MX_GOAL_POSITION);
                if app.dxl_addparam_result ~= true
                    fprintf('[ID:%03d] groupSyncWrite addparam failed', app.DXL2_ID);
                    return;
                end

                app.dxl_addparam_result = groupSyncWriteAddParam(app.group_num, app.DXL3_ID, q3, app.LEN_MX_GOAL_POSITION);
                if app.dxl_addparam_result ~= true
                    fprintf('[ID:%03d] groupSyncWrite addparam failed', app.DXL3_ID);
                    return;
                end

                app.dxl_addparam_result = groupSyncWriteAddParam(app.group_num, app.DXL4_ID, q4, app.LEN_MX_GOAL_POSITION);
                if app.dxl_addparam_result ~= true
                    fprintf('[ID:%03d] groupSyncWrite addparam failed', app.DXL4_ID);
                    return;
                end

                groupSyncWriteTxPacket(app.group_num);
                app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
                if app.dxl_comm_result ~= app.COMM_SUCCESS
                    fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
                end
                groupSyncWriteClearParam(app.group_num);

                
                disp("3");
            elseif app.Button_4.Value==1
                app.q1 = 85*pi/180;
                app.q2 = pi/2-20*pi/180;
                app.q3 = 55*pi/180;
                app.q4 = 25*pi/180;
                app.UITable.Data=["q1",app.q1*180/pi;"q2",app.q2*180/pi-90;"q3",app.q3*180/pi;"q4",app.q4*180/pi;];
                q1=mapeo(app,app.q1*180/pi);
                q2=mapeo(app,(app.q2-pi/2)*180/pi);
                q3=mapeo(app,app.q3*180/pi);
                q4=mapeo(app,app.q4*180/pi);
                
                %mandar al robot a la posicion
                
                app.dxl_addparam_result = groupSyncWriteAddParam(app.group_num, app.DXL1_ID, q1, app.LEN_MX_GOAL_POSITION);
                if app.dxl_addparam_result ~= true
                    fprintf('[ID:%03d] groupSyncWrite addparam failed', app.DXL1_ID);
                    return;
                end
                app.dxl_addparam_result = groupSyncWriteAddParam(app.group_num, app.DXL2_ID, q2, app.LEN_MX_GOAL_POSITION);
                if app.dxl_addparam_result ~= true
                    fprintf('[ID:%03d] groupSyncWrite addparam failed', app.DXL2_ID);
                    return;
                end

                app.dxl_addparam_result = groupSyncWriteAddParam(app.group_num, app.DXL3_ID, q3, app.LEN_MX_GOAL_POSITION);
                if app.dxl_addparam_result ~= true
                    fprintf('[ID:%03d] groupSyncWrite addparam failed', app.DXL3_ID);
                    return;
                end

                app.dxl_addparam_result = groupSyncWriteAddParam(app.group_num, app.DXL4_ID, q4, app.LEN_MX_GOAL_POSITION);
                if app.dxl_addparam_result ~= true
                    fprintf('[ID:%03d] groupSyncWrite addparam failed', app.DXL4_ID);
                    return;
                end

                groupSyncWriteTxPacket(app.group_num);
                app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
                if app.dxl_comm_result ~= app.COMM_SUCCESS
                    fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
                end
                groupSyncWriteClearParam(app.group_num);

                
                disp("4");
            elseif app.Button_5.Value==1
                app.q1 = 80*pi/180;
                app.q2 = pi/2-35*pi/180;
                app.q3 = 55*pi/180;
                app.q4 = -45*pi/180;
                app.UITable.Data=["q1",app.q1*180/pi;"q2",app.q2*180/pi-90;"q3",app.q3*180/pi;"q4",app.q4*180/pi;];
                
                q1=mapeo(app,app.q1*180/pi);
                q2=mapeo(app,(app.q2-pi/2)*180/pi);
                q3=mapeo(app,app.q3*180/pi);
                q4=mapeo(app,app.q4*180/pi);
                
                %mandar al robot a la posicion
                
                app.dxl_addparam_result = groupSyncWriteAddParam(app.group_num, app.DXL1_ID, q1, app.LEN_MX_GOAL_POSITION);
                if app.dxl_addparam_result ~= true
                    fprintf('[ID:%03d] groupSyncWrite addparam failed', app.DXL1_ID);
                    return;
                end
                app.dxl_addparam_result = groupSyncWriteAddParam(app.group_num, app.DXL2_ID, q2, app.LEN_MX_GOAL_POSITION);
                if app.dxl_addparam_result ~= true
                    fprintf('[ID:%03d] groupSyncWrite addparam failed', app.DXL2_ID);
                    return;
                end

                app.dxl_addparam_result = groupSyncWriteAddParam(app.group_num, app.DXL3_ID, q3, app.LEN_MX_GOAL_POSITION);
                if app.dxl_addparam_result ~= true
                    fprintf('[ID:%03d] groupSyncWrite addparam failed', app.DXL3_ID);
                    return;
                end

                app.dxl_addparam_result = groupSyncWriteAddParam(app.group_num, app.DXL4_ID, q4, app.LEN_MX_GOAL_POSITION);
                if app.dxl_addparam_result ~= true
                    fprintf('[ID:%03d] groupSyncWrite addparam failed', app.DXL4_ID);
                    return;
                end

                groupSyncWriteTxPacket(app.group_num);
                app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
                if app.dxl_comm_result ~= app.COMM_SUCCESS
                    fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
                end
                groupSyncWriteClearParam(app.group_num);

                disp("5");
            end
        end

        % Button pushed function: verultimaposicionenviadalButton
        function verultimaposicionenviadalButtonPushed(app, event)
            l_1 = 152.05; 
            l_2 = 136.82; 
            l_3 = 74.12;
            l_4 = 108.4;
            
            % Creación de un objeto de enlace para cada Articulación
            L(1) = Link('revolute', 'qlim', [0 pi], 'd', l_1, 'a', 0, 'alpha', pi/2);
            L(2) = Link('revolute', 'qlim', [0 pi], 'd', 0, 'a', l_2, 'alpha', 0);
            L(3) = Link('revolute', 'qlim', [0 pi], 'd', 0, 'a', l_3, 'alpha', 0);
            L(4) = Link('revolute', 'qlim', [0 pi], 'd', 0, 'a', l_4, 'alpha', 0);
            
            % Creación del Objeto de Robot Serial
            app.ROBOT = SerialLink(L, 'name', 'Robot');
            
            % Definición de la Herramienta (Tool) del Robot
            app.ROBOT.tool = trotx(0) * transl(0, 0, 0);
            
            % Visualización del Robot en la Configuración Inicial
            figure(1)
            xlim([-471 471]);
            ylim([-471 471]);
            
            view([45,30])
            app.ROBOT.plot([app.q1 app.q2 app.q3 app.q4], 'notiles', 'noname');
            zlim([-20 655]);


        end

        % Button pushed function: VerposicionactualButton
        function VerposicionactualButtonPushed(app, event)
            write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL1_ID, app.ADDR_MX_TORQUE_ENABLE, app.TORQUE_DISABLE);
            app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            app.dxl_error = getLastRxPacketError(app.port_num, app.PROTOCOL_VERSION);
            if app.dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
            elseif app.dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, app.dxl_error));
            else
                fprintf('Dynamixel has been successfully connected \n');
            end
            
            write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL2_ID, app.ADDR_MX_TORQUE_ENABLE, app.TORQUE_DISABLE);
            app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            app.dxl_error = getLastRxPacketError(app.port_num, app.PROTOCOL_VERSION);
            if app.dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
            elseif app.dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, app.dxl_error));
            else
                fprintf('Dynamixel has been successfully connected \n');
            end

            write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL3_ID, app.ADDR_MX_TORQUE_ENABLE, app.TORQUE_DISABLE);
            app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            app.dxl_error = getLastRxPacketError(app.port_num, app.PROTOCOL_VERSION);
            if app.dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
            elseif app.dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, app.dxl_error));
            else
                fprintf('Dynamixel has been successfully connected \n');
            end

            write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL4_ID, app.ADDR_MX_TORQUE_ENABLE, app.TORQUE_DISABLE);
            app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            app.dxl_error = getLastRxPacketError(app.port_num, app.PROTOCOL_VERSION);
            if app.dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
            elseif app.dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, app.dxl_error));
            else
                fprintf('Dynamixel has been successfully connected \n');
            end

            app.dxl1_present_position = read2ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL1_ID, app.ADDR_MX_PRESENT_POSITION);
            app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            app.dxl_error = getLastRxPacketError(app.port_num, app.PROTOCOL_VERSION);
            if app.dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
            elseif app.dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, app.dxl_error));
            end
            q1=inversemap(app,app.dxl1_present_position);

            app.dxl2_present_position = read2ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL2_ID, app.ADDR_MX_PRESENT_POSITION);
            app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            app.dxl_error = getLastRxPacketError(app.port_num, app.PROTOCOL_VERSION);
            if app.dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
            elseif app.dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, app.dxl_error));
            end
            q2=inversemap(app,app.dxl2_present_position);

            app.dxl3_present_position = read2ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL3_ID, app.ADDR_MX_PRESENT_POSITION);
            app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            app.dxl_error = getLastRxPacketError(app.port_num, app.PROTOCOL_VERSION);
            if app.dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
            elseif app.dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, app.dxl_error));
            end
            q3=inversemap(app,app.dxl3_present_position);

            app.dxl4_present_position = read2ByteTxRx(app.port_num, app.PROTOCOL_VERSION, app.DXL4_ID, app.ADDR_MX_PRESENT_POSITION);
            app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            app.dxl_error = getLastRxPacketError(app.port_num, app.PROTOCOL_VERSION);
            if app.dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
            elseif app.dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, app.dxl_error));
            end
            q4=inversemap(app,app.dxl4_present_position);


            app.UITable.Data=["q1",q1;"q2",q2;"q3",q3;"q4",q4;];

            %figure(2)
            xlim([-471 471]);
            ylim([-471 471]);
            
            view([45,30])
            
            q1=q1*pi/180;
            q2=(pi/2)+q2*pi/180;
            q3=q3*pi/180;
            q4=q4*pi/180;


            l_1 = 152.05; 
            l_2 = 136.82; 
            l_3 = 74.12;
            l_4 = 108.4;
            
            % Creación de un objeto de enlace para cada Articulación
            L(1) = Link('revolute', 'qlim', [0 pi], 'd', l_1, 'a', 0, 'alpha', pi/2);
            L(2) = Link('revolute', 'qlim', [0 pi], 'd', 0, 'a', l_2, 'alpha', 0);
            L(3) = Link('revolute', 'qlim', [0 pi], 'd', 0, 'a', l_3, 'alpha', 0);
            L(4) = Link('revolute', 'qlim', [0 pi], 'd', 0, 'a', l_4, 'alpha', 0);
            
            % Creación del Objeto de Robot Serial
            app.ROBOT = SerialLink(L, 'name', 'Robot');
            
            % Definición de la Herramienta (Tool) del Robot
            app.ROBOT.tool = trotx(0) * transl(0, 0, 0);
            %app.ROBOT.plot([app.q1 app.q2 app.q3 app.q4], 'notiles', 'noname');
            %zlim([-20 655]);
            app.ROBOT.plot([q1 q2 q3 q4], 'notiles', 'noname');
            zlim([-20 655]);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Posicionesposiblesq1q2q3q4ButtonGroup = uibuttongroup(app.UIFigure);
            app.Posicionesposiblesq1q2q3q4ButtonGroup.Title = 'Posiciones posibles (q1,q2,q3,q4)';
            app.Posicionesposiblesq1q2q3q4ButtonGroup.ButtonDownFcn = createCallbackFcn(app, @Posicionesposiblesq1q2q3q4ButtonGroupButtonDown, true);
            app.Posicionesposiblesq1q2q3q4ButtonGroup.Tag = 'posiciones';
            app.Button = uiradiobutton(app.Posicionesposiblesq1q2q3q4ButtonGroup);
            app.Button.Text = '1=(0,0,0,0)';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.Posicionesposiblesq1q2q3q4ButtonGroup);
            app.Button_2.Text = '2=(25,25,20,-20)';
            app.Button_3 = uiradiobutton(app.Posicionesposiblesq1q2q3q4ButtonGroup);
            app.Button_3.Text = '3=(-35,35,-30,30)';
            app.Button_4 = uiradiobutton(app.Posicionesposiblesq1q2q3q4ButtonGroup);
            app.Button_4.Text = '4=(85,-20,55,25)';
            app.Button_5 = uiradiobutton(app.Posicionesposiblesq1q2q3q4ButtonGroup);
            app.Button_5.Text = '5=(80,-35,55,-45)';
            app.iralaposicinButton = uibutton(app.UIFigure, 'push');
            app.iralaposicinButton.ButtonPushedFcn = createCallbackFcn(app, @iralaposicinButtonPushed, true);
            app.iralaposicinButton.Text = 'ir a la posición';
            app.VictorDavilaManuelCarranzaLabel = uilabel(app.UIFigure);
            app.VictorDavilaManuelCarranzaLabel.Text = 'Victor Davila, Manuel Carranza';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'articulacion'; 'Valor'};
            app.UITable.RowName = {};
            app.verultimaposicionenviadalButton = uibutton(app.UIFigure, 'push');
            app.verultimaposicionenviadalButton.ButtonPushedFcn = createCallbackFcn(app, @verultimaposicionenviadalButtonPushed, true);
            app.verultimaposicionenviadalButton.Text = 'ver ultima posicion enviadal';
            app.VerposicionactualButton = uibutton(app.UIFigure, 'push');
            app.VerposicionactualButton.ButtonPushedFcn = createCallbackFcn(app, @VerposicionactualButtonPushed, true);
            app.VerposicionactualButton.Text = 'Ver posicion actual';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIlab4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end